classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        CButton    matlab.ui.control.Button
        Button_16  matlab.ui.control.Button
        Button_15  matlab.ui.control.Button
        Button_14  matlab.ui.control.Button
        Button_13  matlab.ui.control.Button
        Button_12  matlab.ui.control.Button
        Button_11  matlab.ui.control.Button
        Button_10  matlab.ui.control.Button
        Button_8   matlab.ui.control.Button
        Button_7   matlab.ui.control.Button
        Button_6   matlab.ui.control.Button
        Button_5   matlab.ui.control.Button
        Button_4   matlab.ui.control.Button
        Button_3   matlab.ui.control.Button
        xButton    matlab.ui.control.Button
        Button     matlab.ui.control.Button
        TextArea   matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'0');
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'1');
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'2');
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'3');
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'4');
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'5');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'6');
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'7');
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'8');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextArea.Value = strcat(app.TextArea.Value,'9');
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            assignin("base","a",str2double(app.TextArea.Value));
            assignin("base","c",2);
            app.TextArea.Value = '';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            assignin("base","a",str2double(app.TextArea.Value));
            assignin("base","c",3);
            app.TextArea.Value = '';
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            assignin("base","a",str2double(app.TextArea.Value));
            assignin("base","c",0);
            app.TextArea.Value = '';
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            assignin("base","a",str2double(app.TextArea.Value));
            assignin("base","c",1);
            app.TextArea.Value = '';
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            assignin("base","b",str2double(app.TextArea.Value));
            res = sim("Project1.slx");
            app.TextArea.Value = string(res.result);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.TextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '9';
            app.xButton = uibutton(app.UIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '/';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '+';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '-';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '8';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '6';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '5';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '4';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '3';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '=';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '2';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '1';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '0';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end